classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HelpMenu                     matlab.ui.container.Menu
        MeasuredDistanceTextArea     matlab.ui.control.TextArea
        MeasuredDistanceLabel        matlab.ui.control.Label
        InsertCeilingHeightTextArea  matlab.ui.control.TextArea
        InsertCeilingHeightTextAreaLabel  matlab.ui.control.Label
        MeasureButton                matlab.ui.control.Button
        ShowPointcloudButton         matlab.ui.control.Button
        Image_3                      matlab.ui.control.Image
        Image_2                      matlab.ui.control.Image
        ImportButton                 matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        ReconstructRoomButton        matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        userUpload; 
        maxNumPics = 34;
        params;
        pointCloud;
        model;
        opacity = .2;
        pointCloudButtonPushed = 0;
        scatterPlot;
        numClicks = 0;
        measure = struct('dist',0,'pos1',[0 0 0],'pos2',[0 0 0]);
        ceilingHeight = 240;
        scale;
    end
    
    methods (Access = private)   
        % reads files into array
        function readImages(app, folderpath)   
            files = dir(fullfile(folderpath,'*jpg'));
            cam_file = fullfile(folderpath,'cameras.txt');
            app.params = logic.reconstruct3D.loadCameraParams(cam_file);
            filename = {files.name};            
            filename = filename(~ismember(filename,{'.','..'}));
            images = cell([min(length(files),app.maxNumPics)  1]);
            for i =1:min(length(files),app.maxNumPics)                     
                images{i} = imread(append(folderpath,'/',filename{i}));    
            end
            app.userUpload = images;
            app.Image.ImageSource = append(folderpath,'/',filename{1});
            app.Image.Visible = "on";
            app.Image_2.ImageSource = append(folderpath,'/',filename{2});
            app.Image_2.Visible = "on";
            app.Image_3.ImageSource = append(folderpath,'/',filename{3});
            app.Image_3.Visible = "on"; 
        end           
        
        function plotRoom(app)
            % Input Parser   
            % app.uifig3D = uifigure();
            % app.uiax3D = uiaxes(app.uifig3D, 'Position', [10 10 500 410]); 

            color_floor_ceil = [1 1 1];
            floor = app.model{1};
            ceil = app.model{2};
            cuboids = app.model{3};
            
            P = zeros(8,3,length(cuboids));           
            % idx tells in which order plot should connect dots
            idx = [4 8 5 1 4; 1 5 6 2 1; 2 6 7 3 2;...
                    3 7 8 4 3; 5 8 7 6 5; 1 4 3 2 1]';
            % store the cuboid plot objects
            for i = 1:length(cuboids)    
                P(:,:,i) = getCornerPoints(cuboids{i});
                xc = P(:,1,i);
                yc = P(:,2,i);
                zc = P(:,3,i);                
                patch(xc(idx), yc(idx), zc(idx), 'k', ...
                   'facealpha', app.opacity, Parent=app.UIAxes);
                view(app.UIAxes,3);    
                hold(app.UIAxes,'on');
            end
            plot(floor,Color=color_floor_ceil,Parent=app.UIAxes);
            plot(ceil,Color=color_floor_ceil,Parent=app.UIAxes);            
            app.ShowPointcloudButton.Visible = 'on';    
            app.UIAxes.Interactions = [rotateInteraction zoomInteraction];
        end         
        
        function txt = Measure(app,~,info,dcm)                
            app.numClicks = mod(app.numClicks+1,2);                
            if(app.numClicks)
                app.measure.pos1 = info.Position;            
            else                            
                app.measure.pos2 = info.Position;
                hold(app.UIAxes,'on');
                plot3([app.measure.pos1(1) app.measure.pos2(1)],[app.measure.pos1(2) app.measure.pos2(2)],...
                      [app.measure.pos1(3) app.measure.pos2(3)],LineWidth = 2,Parent=app.UIAxes);
                app.measure.dist = app.scale*norm(app.measure.pos1-app.measure.pos2);  
                app.MeasuredDistanceTextArea.Value{1} = num2str(app.measure.dist);                
                dcm.Enable = 'off';
                app.UIAxes.Interactions = [rotateInteraction zoomInteraction];
            end           
            txt = 'Measured Data';
        end        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            %value = app.FilenameEditField.Value; 
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            path = uigetdir('','Select folder');
            if(~path)
                % user canceled data import
                return
            end
            readImages(app, path);
            app.FilenameEditField.Value = path;  
            figure(app.UIFigure);
        end

        % Button pushed function: ReconstructRoomButton
        function ReconstructRoomButtonPushed(app, event)
            m = struct2cell(load('+gui/testModel.mat'));
            m = m{1}; 
            progress = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                            'Message','Reconstruction in progress');
            [m, pc,scalingFactor] = pipeline(app.userUpload,app.params,app.ceilingHeight,progress);
            app.scale = scalingFactor;
            %pc = struct2cell(load('testCloud.mat'));
            app.pointCloud = pc;
            app.model = m; 
            plotRoom(app);
        end

        % Button pushed function: ShowPointcloudButton
        function ShowPointcloudButtonPushed(app, event)
            if(app.pointCloudButtonPushed)
                if(app.scatterPlot.Visible == "on")
                    app.scatterPlot.Visible = "off";
                    app.ShowPointcloudButton.Text = "Show Pointcloud";
                else
                    app.scatterPlot.Visible = "on";
                    app.ShowPointcloudButton.Text = "Hide Pointcloud";
                end
            else
                x = app.pointCloud.Location(:, 1);          
                y = app.pointCloud.Location(:, 2);
                z = app.pointCloud.Location(:, 3);
                c = app.pointCloud.Color;
                tab = table(x,y,z,c);
                app.scatterPlot = scatter3(tab,'x','y','z','filled',...
                    'ColorVariable','c',LineWidth=.1,Parent=app.UIAxes);  
                app.scatterPlot.SizeData=10;
                app.scatterPlot.Marker='square';
                app.pointCloudButtonPushed = 1;           
                app.ShowPointcloudButton.Text = "Hide Pointcloud";
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            dcm = datacursormode(app.UIFigure);
            dcm.Enable = 'on'; 
            dcm.UpdateFcn = @(hObj,event,ax) Measure(app,hObj,event,dcm);            
        end

        % Value changed function: InsertCeilingHeightTextArea
        function InsertCeilingHeightTextAreaValueChanged(app, event)
            app.ceilingHeight = app.InsertCeilingHeightTextArea.Value;            
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.ReconstructRoomButton = uibutton(app.UIFigure, 'push');
            app.ReconstructRoomButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructRoomButtonPushed, true);
            app.ReconstructRoomButton.Text = 'Reconstruct Room';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Visible = 'off';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.Visible = 'off';
            app.ShowPointcloudButton = uibutton(app.UIFigure, 'push');
            app.ShowPointcloudButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPointcloudButtonPushed, true);
            app.ShowPointcloudButton.Visible = 'off';
            app.ShowPointcloudButton.Text = 'Show Pointcloud';
            app.MeasureButton = uibutton(app.UIFigure, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.InsertCeilingHeightTextAreaLabel = uilabel(app.UIFigure);
            app.InsertCeilingHeightTextAreaLabel.Text = 'Insert Ceiling Height';
            app.InsertCeilingHeightTextArea = uitextarea(app.UIFigure);
            app.InsertCeilingHeightTextArea.ValueChangedFcn = createCallbackFcn(app, @InsertCeilingHeightTextAreaValueChanged, true);
            app.InsertCeilingHeightTextArea.Placeholder = 'Default = 240cm';
            app.MeasuredDistanceLabel = uilabel(app.UIFigure);
            app.MeasuredDistanceLabel.Text = 'Measured Distance:';
            app.MeasuredDistanceTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end